classdef Text_Encryption_using_Extended_Bit_Circular_Shift_Cipher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtendedBitCircularShiftCipherUIFigure  matlab.ui.Figure
        LogMenu                        matlab.ui.container.Menu
        EncryptionMenu                 matlab.ui.container.Menu
        DecryptionMenu                 matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
        ResetLog                       matlab.ui.container.Menu
        ResetData                      matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        ProcessTab                     matlab.ui.container.Tab
        ImageEnc                       matlab.ui.control.Image
        InputTextLabelEnc              matlab.ui.control.Label
        inputtextEnc                   matlab.ui.control.EditField
        InputFileButtonEnc             matlab.ui.control.Button
        InputKeyLabelEnc               matlab.ui.control.Label
        inputkeyEnc                    matlab.ui.control.EditField
        CipherTextLabelEnc             matlab.ui.control.Label
        ciphertextEnc                  matlab.ui.control.EditField
        OpenFileButtonEnc              matlab.ui.control.Button
        OpenFolderButtonEnc            matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        SaveFileButtonEnc              matlab.ui.control.Button
        AvalancheEffectEditFieldLabel  matlab.ui.control.Label
        AvalancheEffectEditField       matlab.ui.control.EditField
        BitErrorRateEditFieldLabel     matlab.ui.control.Label
        BitErrorRateEditField          matlab.ui.control.EditField
        ExtendedBitCircularShiftCipherLabel  matlab.ui.control.Label
        PlaintextLabelDec              matlab.ui.control.Label
        plaintextDec                   matlab.ui.control.EditField
        OpenFileButtonDec              matlab.ui.control.Button
        OpenFolderButtonDec            matlab.ui.control.Button
        SaveFileButtonDec              matlab.ui.control.Button
        CEREditFieldLabel              matlab.ui.control.Label
        CEREditField                   matlab.ui.control.EditField
        ResetButton                    matlab.ui.control.Button
        InputFileButtonKey             matlab.ui.control.Button
        GraphTab                       matlab.ui.container.Tab
        ImageGraph                     matlab.ui.control.Image
        AvalancheEffectLabel           matlab.ui.control.Label
        BitErrorRateLabel              matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        UIAxesAE                       matlab.ui.control.UIAxes
        UIAxesBER                      matlab.ui.control.UIAxes
        UIAxesTIMEENC                  matlab.ui.control.UIAxes
        CalculateButton                matlab.ui.control.Button
        ResetGraphButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        %%
        originaltextenc
        originaltextdec
        graphaep
        graphberp
        graphtimeEnc
        graphtimeDec
        elapsedEnc
        elapsedDec
        AEplot
        BERplot
        TimeEncplot
        TimeDecplot
        
        % Encrypt
        encrypt
        aep
        berp
        
        % Decrypt
        cerp
        decrypt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.originaltextenc = app.inputtextEnc.Value; % input text
            key = app.inputkeyEnc.Value; % input key
            Ltext = length(app.originaltextenc); % length text
            Lkey = length(key); % length key
            Dkey = double(key); % merubah key menjadi double
            sumkey = 0;
            
            for a=1:Lkey
                sumkey=sumkey+Dkey(a);
            end
            
            shiftkey1=mod(sumkey,7)+1; % shift bit cipher
            shiftkey2=mod(sumkey,Lkey-1)+1; % shift extended bit cipher
            
            % ====== ENCRYPTION ======
            
            tic
            for b=1:Ltext
                binarytext=dec2bin(app.originaltextenc(b),8);
                c=1;
                for x=(8-shiftkey1+1):8
                    shifttext(c)=binarytext(x);
                    c=c+1;
                end
                for x=c:8
                    shifttext(c)=binarytext(x-shiftkey1);
                    c=c+1;
                end
                cipher1z(b)=bin2dec(shifttext);
            end
            app.encrypt=char(circshift(cipher1z,shiftkey2));
            toc
            
            app.elapsedEnc = toc;
            fideEnc = fopen(fullfile('eEnc.txt'), 'a');
            fprintf(fideEnc, '%f \n', app.elapsedEnc);
            fclose(fideEnc);
            
            app.ciphertextEnc.Value = app.encrypt;
            
            % AE
            ae=0;
            [y1,x1] = size(app.originaltextenc);
            k1=reshape(app.originaltextenc, [1,y1*x1]);
            k2=reshape(app.encrypt, [1,y1*x1]);
            k1=dec2bin(k1,8);
            k2=dec2bin(k2,8);
            k1=reshape(k1, [1,y1*x1*8]);
            k2=reshape(k2, [1,y1*x1*8]);
            for m=1:(x1*y1*8)
                if k1(m) ~= k2(m)
                    ae=ae+1;
                end
            end
            app.aep=(ae/(y1*x1*8)) * 100;
            app.AvalancheEffectEditField.Value = sprintf('%.4f%%',app.aep);
            fidAE = fopen(fullfile('AE.txt'), 'a');
            fprintf(fidAE, '%.4f \n', app.aep);
            fclose(fidAE);
            
            
            % BER
            ber=0;
            [b1,a1] = size(app.originaltextenc);
            c1=reshape(app.originaltextenc, [1,b1*a1]);
            c2=reshape(cipher1z, [1,b1*a1]);
            c1=dec2bin(c1,8);
            c2=dec2bin(c2,8);
            c1=reshape(c1, [1,b1*a1*8]);
            c2=reshape(c2, [1,b1*a1*8]);
            for d=1:(a1*b1*8)
                if c1(d) ~= c2(d)
                    ber=ber+1;
                end
            end
            app.berp=(ber/(b1*a1*8)) * 100;
            app.BitErrorRateEditField.Value = sprintf('%.4f%%',app.berp);
            fidBER = fopen(fullfile('BER.txt'), 'a');
            fprintf(fidBER, '%.4f \n', app.berp);
            fclose(fidBER);
            
            yourMsg = app.encrypt;
            fid = fopen(fullfile('EncryptionLog.txt'), 'a');
            if fid == -1
              error('Cannot open log file.');
            end
            fprintf(fid, '%s: %s | AE = %f%% | BER = %f%% \n', datestr(now, 0), yourMsg, app.aep, app.berp);
            fclose(fid);
            
            fidz = fopen(fullfile('plaintext.txt'),'wt');
            fprintf(fidz, '%s', app.inputtextEnc.Value);
            fclose(fidz);
            
            % ====== DECRYPTION ======
            
            tic
            decrypt1z=circshift(app.encrypt,-shiftkey2);
            for b=1:Ltext
                binarytext=dec2bin(decrypt1z(b),8);
                c=1;
                for x=(shiftkey1+1):8
                    shifttext(c)=binarytext(x);
                    c=c+1;
                end
                for x=1:shiftkey1
                    shifttext(c)=binarytext(x);
                    c=c+1;
                end
                decrypt2z(b)=bin2dec(shifttext);
            end
            app.decrypt = char(decrypt2z);
            toc
            
            app.elapsedDec = toc;
            fideDec = fopen(fullfile('eDec.txt'), 'a');
            fprintf(fideDec, '%f \n', app.elapsedDec);
            fclose(fideDec);
            
            app.plaintextDec.Value = app.decrypt;
            
            % CER
            fidz=fopen(fullfile('plaintext.txt'),'rt');
            format = '%c';
            xz = fscanf(fidz,format);
            z = strtrim(xz);
            
            cer=0;
            [b1,a1] = size(z);
            c1=reshape(z, [1,b1*a1]);
            c2=reshape(app.decrypt, [1,b1*a1]);
            c1=dec2bin(c1,8);
            c2=dec2bin(c2,8);
            c1=reshape(c1, [1,b1*a1*8]);
            c2=reshape(c2, [1,b1*a1*8]);
            for d=1:(a1*b1*8)
                if c1(d) ~= c2(d)
                    cer=cer+1;
                end
            end
            app.cerp=(cer/(b1*a1*8)) * 100;
            app.CEREditField.Value = sprintf('%.4f%%',app.cerp);
            
            yourMsg = app.decrypt;
            fid = fopen(fullfile('DecryptionLog.txt'), 'a');
            if fid == -1
              error('Cannot open log file.');
            end
            fprintf(fid, '%s: %s | CER = %f%% \n', datestr(now, 0), yourMsg, app.cerp);
            fclose(fid);
        end

        % Button pushed function: InputFileButtonEnc
        function InputFileButtonEncPushed(app, event)
            [file,~]=uigetfile({'*.txt'},'Select an Text File');
            if isequal(file,0)
                disp('Nothing Selected')
            else
              
               try
                   inputfile = fopen(fullfile(file),'rt');
                   format = '%c';
                   txt = fscanf(inputfile,format);
                   newtxt = txt;
                   app.inputtextEnc.Value = newtxt;
              catch 
                    errordlg(['There was a problem loading the .txt',' file'],'Load Error!');
                    return;
               end
            end
        end

        % Button pushed function: OpenFileButtonEnc
        function OpenFileButtonEncPushed(app, event)
            sf = fopen(fullfile('encryption.txt'),'wt');
            fprintf(sf, '%c', app.encrypt);
            winopen('encryption.txt');
        end

        % Button pushed function: OpenFolderButtonEnc
        function OpenFolderButtonEncPushed(app, event)
            winopen(cd);
        end

        % Button pushed function: SaveFileButtonEnc
        function SaveFileButtonEncPushed(app, event)
            txt=app.encrypt;
            [savefile,path] = uiputfile('*.txt');
            if isequal(savefile,0) || isequal(path,0)
               disp('Nothing Selected')
            else
                try
                    sf = fopen(fullfile(savefile),'wt');
                    fprintf(sf, '%c', txt);
%                     fprintf(sf, '%s', app.encrypt);
%                     msgbox('Save Complete','Success');
                catch
                    errordlg(['There was a problem save the .txt',' file'],'Save Error!');
                    return;
                end
            end
        end

        % Menu selected function: EncryptionMenu
        function EncryptionMenuSelected(app, event)
            winopen('EncryptionLog.txt');
        end

        % Menu selected function: DecryptionMenu
        function DecryptionMenuSelected(app, event)
            winopen('DecryptionLog.txt');
        end

        % Button pushed function: OpenFileButtonDec
        function OpenFileButtonDecPushed(app, event)
            sf = fopen(fullfile('decryption.txt'),'wt');
            fprintf(sf, '%c', app.decrypt);
            winopen('decryption.txt');
        end

        % Button pushed function: OpenFolderButtonDec
        function OpenFolderButtonDecPushed(app, event)
            winopen(cd);
        end

        % Button pushed function: SaveFileButtonDec
        function SaveFileButtonDecPushed(app, event)
            [savefile,path] = uiputfile('*.txt');
            if isequal(savefile,0) || isequal(path,0)
               disp('Nothing Selected')
            else
                try
                    sf = fopen(fullfile(savefile),'wt');
                    fprintf(sf, '%c', app.decrypt);
%                     msgbox('Save Complete','Success');
                catch
                    errordlg(['There was a problem save the .txt',' file'],'Save Error!');
                    return;
                end
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % AE
            fidAE = fopen(fullfile('AE.txt'),'rt');
            app.graphaep = fscanf(fidAE,'%f');
            Lgraphaep = length(app.graphaep);
            for xaep=1:Lgraphaep
                zaep(xaep) = app.graphaep(xaep);
            end
            app.AEplot = plot(app.UIAxesAE,1:Lgraphaep,zaep);
            
            % BER
            fidBER = fopen(fullfile('BER.txt'),'rt');
            app.graphberp = fscanf(fidBER,'%f');
            Lgraphberp = length(app.graphberp);
            for xberp=1:Lgraphberp
                zberp(xberp) = app.graphberp(xberp);
            end
            app.BERplot = plot(app.UIAxesBER,1:Lgraphberp,zberp);
            
            % TIME ENC
            fidTimeEnc = fopen(fullfile('eEnc.txt'),'rt');
            app.graphtimeEnc = fscanf(fidTimeEnc,'%f');
            LgraphtimeEnc = length(app.graphtimeEnc);
            for xtimeenc=1:LgraphtimeEnc
                ztimeenc(xtimeenc) = app.graphtimeEnc(xtimeenc);
            end
            
            % TIME DEC
            fidTimeDec = fopen(fullfile('eDec.txt'),'rt');
            app.graphtimeDec = fscanf(fidTimeDec,'%f');
            LgraphtimeDec = length(app.graphtimeDec);
            for xtimedec=1:LgraphtimeDec
                ztimedec(xtimedec) = app.graphtimeDec(xtimedec);
            end
            app.TimeEncplot = plot(app.UIAxesTIMEENC,1:LgraphtimeEnc,ztimeenc, '-r');
            hold(app.UIAxesTIMEENC,'on');
            app.TimeDecplot = plot(app.UIAxesTIMEENC,1:LgraphtimeDec,ztimedec, '-b');
            hold(app.UIAxesTIMEENC,'off');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.inputtextEnc.Value = "";
            app.inputkeyEnc.Value = "";
            app.ciphertextEnc.Value = "";
            app.plaintextDec.Value = "";
            app.AvalancheEffectEditField.Value = "%";
            app.BitErrorRateEditField.Value = "%";
            app.CEREditField.Value = "%";
        end

        % Button pushed function: ResetGraphButton
        function ResetGraphButtonPushed(app, event)
            delete(app.AEplot);
            delete(app.BERplot);
            delete(app.TimeEncplot);
            delete(app.TimeDecplot);
        end

        % Menu selected function: ResetLog
        function ResetLogSelected(app, event)
            fidEncLog = fopen(fullfile('EncryptionLog.txt'), 'wt');
            fprintf(fidEncLog, '');
            fclose(fidEncLog);
            
            fidDecLog = fopen(fullfile('DecryptionLog.txt'), 'wt');
            fprintf(fidDecLog, '');
            fclose(fidDecLog);
            
            msgbox('Reset Log Complete','Message','help');
        end

        % Menu selected function: ResetData
        function ResetDataMenuSelected(app, event)
            fidAEz = fopen(fullfile('AE.txt'), 'wt');
            fprintf(fidAEz, '');
            fclose(fidAEz);
            
            fidBERz = fopen(fullfile('BER.txt'), 'wt');
            fprintf(fidBERz, '');
            fclose(fidBERz);
            
            fidEnc = fopen(fullfile('encryption.txt'), 'wt');
            fprintf(fidEnc, '');
            fclose(fidEnc);
            
            fidDec = fopen(fullfile('decryption.txt'), 'wt');
            fprintf(fidDec, '');
            fclose(fidDec);
            
            fideEnc = fopen(fullfile('eEnc.txt'), 'wt');
            fprintf(fideEnc, '');
            fclose(fideEnc);
            
            fideDec = fopen(fullfile('eDec.txt'), 'wt');
            fprintf(fideDec, '');
            fclose(fideDec);
            
            fidPT = fopen(fullfile('plaintext.txt'), 'wt');
            fprintf(fidPT, '');
            fclose(fidPT);
            
            msgbox('Reset Data Complete','Message','help');
        end

        % Button pushed function: InputFileButtonKey
        function InputFileButtonKeyPushed(app, event)
            [file,~]=uigetfile({'*.txt'},'Select an Text File');
            if isequal(file,0)
                disp('Nothing Selected')
            else
              
               try
                   inputfile = fopen(fullfile(file),'rt');
                   format = '%c';
                   txt = fscanf(inputfile,format);
                   newtxt = txt;
                   app.inputtextEnc.Value = newtxt;
              catch 
                    errordlg(['There was a problem loading the .txt',' file'],'Load Error!');
                    return;
               end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtendedBitCircularShiftCipherUIFigure = uifigure('Visible', 'off');
            app.ExtendedBitCircularShiftCipherUIFigure.Name = 'Extended Bit Circular Shift Cipher';
            app.LogMenu = uimenu(app.ExtendedBitCircularShiftCipherUIFigure);
            app.LogMenu.Text = 'Log';
            app.EncryptionMenu = uimenu(app.LogMenu);
            app.EncryptionMenu.MenuSelectedFcn = createCallbackFcn(app, @EncryptionMenuSelected, true);
            app.EncryptionMenu.Text = 'Encryption';
            app.DecryptionMenu = uimenu(app.LogMenu);
            app.DecryptionMenu.MenuSelectedFcn = createCallbackFcn(app, @DecryptionMenuSelected, true);
            app.DecryptionMenu.Text = 'Decryption';
            app.ResetMenu = uimenu(app.LogMenu);
            app.ResetMenu.Text = 'Reset';
            app.ResetLog = uimenu(app.ResetMenu);
            app.ResetLog.MenuSelectedFcn = createCallbackFcn(app, @ResetLogSelected, true);
            app.ResetLog.Text = 'Log';
            app.ResetData = uimenu(app.ResetMenu);
            app.ResetData.MenuSelectedFcn = createCallbackFcn(app, @ResetDataMenuSelected, true);
            app.ResetData.Text = 'Data';
            app.TabGroup = uitabgroup(app.ExtendedBitCircularShiftCipherUIFigure);
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.ImageEnc = uiimage(app.ProcessTab);
            app.ImageEnc.Tooltip = {''};
            app.ImageEnc.ImageSource = 'cover.jpg';
            app.InputTextLabelEnc = uilabel(app.ProcessTab);
            app.InputTextLabelEnc.Text = 'Input Text';
            app.inputtextEnc = uieditfield(app.ProcessTab, 'text');
            app.InputFileButtonEnc = uibutton(app.ProcessTab, 'push');
            app.InputFileButtonEnc.ButtonPushedFcn = createCallbackFcn(app, @InputFileButtonEncPushed, true);
            app.InputFileButtonEnc.Text = 'Input File';
            app.InputKeyLabelEnc = uilabel(app.ProcessTab);
            app.InputKeyLabelEnc.Text = 'Input Key';
            app.inputkeyEnc = uieditfield(app.ProcessTab, 'text');
            app.CipherTextLabelEnc = uilabel(app.ProcessTab);
            app.CipherTextLabelEnc.Text = 'Cipher Text';
            app.ciphertextEnc = uieditfield(app.ProcessTab, 'text');
            app.OpenFileButtonEnc = uibutton(app.ProcessTab, 'push');
            app.OpenFileButtonEnc.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonEncPushed, true);
            app.OpenFileButtonEnc.Text = 'Open File';
            app.OpenFolderButtonEnc = uibutton(app.ProcessTab, 'push');
            app.OpenFolderButtonEnc.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonEncPushed, true);
            app.OpenFolderButtonEnc.Text = 'Open Folder';
            app.ProcessButton = uibutton(app.ProcessTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'PROCESS';
            app.SaveFileButtonEnc = uibutton(app.ProcessTab, 'push');
            app.SaveFileButtonEnc.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonEncPushed, true);
            app.SaveFileButtonEnc.Text = 'Save File';
            app.AvalancheEffectEditFieldLabel = uilabel(app.ProcessTab);
            app.AvalancheEffectEditFieldLabel.Text = 'Avalanche Effect';
            app.AvalancheEffectEditField = uieditfield(app.ProcessTab, 'text');
            app.AvalancheEffectEditField.Value = '%';
            app.BitErrorRateEditFieldLabel = uilabel(app.ProcessTab);
            app.BitErrorRateEditFieldLabel.Text = 'Bit Error Rate';
            app.BitErrorRateEditField = uieditfield(app.ProcessTab, 'text');
            app.BitErrorRateEditField.Value = '%';
            app.ExtendedBitCircularShiftCipherLabel = uilabel(app.ProcessTab);
            app.ExtendedBitCircularShiftCipherLabel.Text = 'Extended Bit Circular Shift Cipher';
            app.PlaintextLabelDec = uilabel(app.ProcessTab);
            app.PlaintextLabelDec.Text = 'Plain Text';
            app.plaintextDec = uieditfield(app.ProcessTab, 'text');
            app.OpenFileButtonDec = uibutton(app.ProcessTab, 'push');
            app.OpenFileButtonDec.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonDecPushed, true);
            app.OpenFileButtonDec.Text = 'Open File';
            app.OpenFolderButtonDec = uibutton(app.ProcessTab, 'push');
            app.OpenFolderButtonDec.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonDecPushed, true);
            app.OpenFolderButtonDec.Text = 'Open Folder';
            app.SaveFileButtonDec = uibutton(app.ProcessTab, 'push');
            app.SaveFileButtonDec.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonDecPushed, true);
            app.SaveFileButtonDec.Text = 'Save File';
            app.CEREditFieldLabel = uilabel(app.ProcessTab);
            app.CEREditFieldLabel.Text = 'Character Error Rate';
            app.CEREditField = uieditfield(app.ProcessTab, 'text');
            app.CEREditField.Value = '%';
            app.ResetButton = uibutton(app.ProcessTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InputFileButtonKey = uibutton(app.ProcessTab, 'push');
            app.InputFileButtonKey.ButtonPushedFcn = createCallbackFcn(app, @InputFileButtonKeyPushed, true);
            app.InputFileButtonKey.Text = 'Input File';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.ImageGraph = uiimage(app.GraphTab);
            app.ImageGraph.ImageSource = 'cover.jpg';
            app.AvalancheEffectLabel = uilabel(app.GraphTab);
            app.AvalancheEffectLabel.Text = 'Avalanche Effect';
            app.BitErrorRateLabel = uilabel(app.GraphTab);
            app.BitErrorRateLabel.Text = 'Bit Error Rate';
            app.TimeLabel = uilabel(app.GraphTab);
            app.TimeLabel.Text = 'Time';
            app.UIAxesAE = uiaxes(app.GraphTab);
            title(app.UIAxesAE, 'AE')
            xlabel(app.UIAxesAE, 'Jumlah')
            ylabel(app.UIAxesAE, 'Persentase')
            app.UIAxesBER = uiaxes(app.GraphTab);
            title(app.UIAxesBER, 'BER')
            xlabel(app.UIAxesBER, 'Jumlah')
            ylabel(app.UIAxesBER, 'Persentase')
            app.UIAxesTIMEENC = uiaxes(app.GraphTab);
            title(app.UIAxesTIMEENC, 'Time')
            xlabel(app.UIAxesTIMEENC, 'Jumlah')
            ylabel(app.UIAxesTIMEENC, 'Time(seconds)')
            app.CalculateButton = uibutton(app.GraphTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetGraphButton = uibutton(app.GraphTab, 'push');
            app.ResetGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGraphButtonPushed, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            % Show the figure after all components are created
            app.ExtendedBitCircularShiftCipherUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Text_Encryption_using_Extended_Bit_Circular_Shift_Cipher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExtendedBitCircularShiftCipherUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtendedBitCircularShiftCipherUIFigure)
        end
    end
end